classdef ledcontroll_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        popupmenu1   matlab.ui.control.DropDown
        popupmenu2   matlab.ui.control.DropDown
        popupmenu3   matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ledcontroll_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ledcontroll (see VARARGIN)
            %ar = serial('COM4','BaudRate',115200,'DataBits',8);
            %fopen(ar);
            instrreset;
            handles.byte1=64;
            handles.byte2=128;
            handles.byte3=192;
            info = instrhwinfo('serial');
            if isempty(info.AvailableSerialPorts)
               error('No ports free!');
            end
            handles.s = serial(info.AvailableSerialPorts{1}, 'BaudRate', 115200);
            fopen(handles.s);
            % Choose default command line output for ledcontroll
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            val = get(hObject,'Value');
            ledOffset=64;
            switch val
                case 1
                    handles.byte1 = uint8(ledOffset+0);
                case 2
                    handles.byte1 = uint8(ledOffset+55);
                case 3
                    handles.byte1 = uint8(ledOffset+47);
                case 4
                    handles.byte1 = uint8(ledOffset+54);
                case 5
                    handles.byte1 = uint8(ledOffset+46);
                case 6
                    handles.byte1 = uint8(ledOffset+53);
                case 7
                    handles.byte1 = uint8(ledOffset+45);
                case 8
                    handles.byte1 = uint8(ledOffset+39);
                case 9
                    handles.byte1 = uint8(ledOffset+52);
                case 10
                    handles.byte1 = uint8(ledOffset+31);
                case 11
                    handles.byte1 = uint8(ledOffset+44);
                case 12
                    handles.byte1 = uint8(ledOffset+38);
                case 13
                    handles.byte1 = uint8(ledOffset+30);
                case 14
                    handles.byte1 = uint8(ledOffset+51);
                case 15
                    handles.byte1 = uint8(ledOffset+23);
                case 16
                    handles.byte1 = uint8(ledOffset+15);
                case 17
                    handles.byte1 = uint8(ledOffset+37);
                case 18
                    handles.byte1 = uint8(ledOffset+43);
                case 19
                    handles.byte1 = uint8(ledOffset+22);
                case 20
                    handles.byte1 = uint8(ledOffset+29);
                case 21
                    handles.byte1 = uint8(ledOffset+14);
                case 22
                    handles.byte1 = uint8(ledOffset+36);
                case 23
                    handles.byte1 = uint8(ledOffset+50);
                case 24
                    handles.byte1 = uint8(ledOffset+21);
                case 25
                    handles.byte1 = uint8(ledOffset+13);
                case 26
                    handles.byte1 = uint8(ledOffset+28);
                case 27
                    handles.byte1 = uint8(ledOffset+42);
                case 28
                    handles.byte1 = uint8(ledOffset+20);
                case 29
                    handles.byte1 = uint8(ledOffset+35);
                case 30
                    handles.byte1 = uint8(ledOffset+12);
                case 31
                    handles.byte1 = uint8(ledOffset+27);
                case 32
                    handles.byte1 = uint8(ledOffset+19);
                case 33
                    handles.byte1 = uint8(ledOffset+49);
                case 34
                    handles.byte1 = uint8(ledOffset+11);
                case 35
                    handles.byte1 = uint8(ledOffset+34);
                case 36
                    handles.byte1 = uint8(ledOffset+41);
                case 37
                    handles.byte1 = uint8(ledOffset+26);
                case 38
                    handles.byte1 = uint8(ledOffset+18);
                case 39
                    handles.byte1 = uint8(ledOffset+10);
                case 40
                    handles.byte1 = uint8(ledOffset+33);
                case 41
                    handles.byte1 = uint8(ledOffset+25);
                case 42
                    handles.byte1 = uint8(ledOffset+17);
                case 43
                    handles.byte1 = uint8(ledOffset+48);
                case 44
                    handles.byte1 = uint8(ledOffset+9);
                case 45
                    handles.byte1 = uint8(ledOffset+40);
                case 46
                    handles.byte1 = uint8(ledOffset+32);
                case 47
                    handles.byte1 = uint8(ledOffset+24);
                case 48
                    handles.byte1 = uint8(ledOffset+16);
                case 49
                   handles.byte1 = uint8(ledOffset+8);
                 case 50 %%%constant
                   handles.byte1 = uint8(ledOffset+56);
            end
            
            guidata(hObject,handles)
        end

        % Value changed function: popupmenu2
        function popupmenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            val = get(hObject,'Value');
            ledOffset=128;
            switch val
                case 1
                    handles.byte2 = uint8(ledOffset+0);
                case 2
                    handles.byte2 = uint8(ledOffset+55);
                case 3
                    handles.byte2 = uint8(ledOffset+47);
                case 4
                    handles.byte2 = uint8(ledOffset+54);
                case 5
                    handles.byte2 = uint8(ledOffset+46);
                case 6
                    handles.byte2 = uint8(ledOffset+53);
                case 7
                    handles.byte2 = uint8(ledOffset+45);
                case 8
                    handles.byte2 = uint8(ledOffset+39);
                case 9
                    handles.byte2 = uint8(ledOffset+52);
                case 10
                    handles.byte2 = uint8(ledOffset+31);
                case 11
                    handles.byte2 = uint8(ledOffset+44);
                case 12
                    handles.byte2 = uint8(ledOffset+38);
                case 13
                    handles.byte2 = uint8(ledOffset+30);
                case 14
                    handles.byte2 = uint8(ledOffset+51);
                case 15
                    handles.byte2 = uint8(ledOffset+23);
                case 16
                    handles.byte2 = uint8(ledOffset+15);
                case 17
                    handles.byte2 = uint8(ledOffset+37);
                case 18
                    handles.byte2 = uint8(ledOffset+43);
                case 19
                    handles.byte2 = uint8(ledOffset+22);
                case 20
                    handles.byte2 = uint8(ledOffset+29);
                case 21
                    handles.byte2 = uint8(ledOffset+14);
                case 22
                    handles.byte2 = uint8(ledOffset+36);
                case 23
                    handles.byte2 = uint8(ledOffset+50);
                case 24
                    handles.byte2 = uint8(ledOffset+21);
                case 25
                    handles.byte2 = uint8(ledOffset+13);
                case 26
                    handles.byte2 = uint8(ledOffset+28);
                case 27
                    handles.byte2 = uint8(ledOffset+42);
                case 28
                    handles.byte2 = uint8(ledOffset+20);
                case 29
                    handles.byte2 = uint8(ledOffset+35);
                case 30
                    handles.byte2 = uint8(ledOffset+12);
                case 31
                    handles.byte2 = uint8(ledOffset+27);
                case 32
                    handles.byte2 = uint8(ledOffset+19);
                case 33
                    handles.byte2 = uint8(ledOffset+49);
                case 34
                    handles.byte2 = uint8(ledOffset+11);
                case 35
                    handles.byte2 = uint8(ledOffset+34);
                case 36
                    handles.byte2 = uint8(ledOffset+41);
                case 37
                    handles.byte2 = uint8(ledOffset+26);
                case 38
                    handles.byte2 = uint8(ledOffset+18);
                case 39
                    handles.byte2 = uint8(ledOffset+10);
                case 40
                    handles.byte2 = uint8(ledOffset+33);
                case 41
                    handles.byte2 = uint8(ledOffset+25);
                case 42
                    handles.byte2 = uint8(ledOffset+17);
                case 43
                    handles.byte2 = uint8(ledOffset+48);
                case 44
                    handles.byte2 = uint8(ledOffset+9);
                case 45
                    handles.byte2 = uint8(ledOffset+40);
                case 46
                    handles.byte2 = uint8(ledOffset+32);
                case 47
                    handles.byte2 = uint8(ledOffset+24);
                case 48
                    handles.byte2 = uint8(ledOffset+16);
                case 49
                   handles.byte2 = uint8(ledOffset+8);
                case 50 %%%constant
                   handles.byte2 = uint8(ledOffset+56);
            end
            
            guidata(hObject,handles)
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            val = get(hObject,'Value');
            ledOffset=192;
            switch val
                case 1
                    handles.byte3 = uint8(ledOffset+0);
                case 2
                    handles.byte3 = uint8(ledOffset+55);
                case 3
                    handles.byte3 = uint8(ledOffset+47);
                case 4
                    handles.byte3 = uint8(ledOffset+54);
                case 5
                    handles.byte3 = uint8(ledOffset+46);
                case 6
                    handles.byte3 = uint8(ledOffset+53);
                case 7
                    handles.byte3 = uint8(ledOffset+45);
                case 8
                    handles.byte3 = uint8(ledOffset+39);
                case 9
                    handles.byte3 = uint8(ledOffset+52);
                case 10
                    handles.byte3 = uint8(ledOffset+31);
                case 11
                    handles.byte3 = uint8(ledOffset+44);
                case 12
                    handles.byte3 = uint8(ledOffset+38);
                case 13
                    handles.byte3 = uint8(ledOffset+30);
                case 14
                    handles.byte3 = uint8(ledOffset+51);
                case 15
                    handles.byte3 = uint8(ledOffset+23);
                case 16
                    handles.byte3 = uint8(ledOffset+15);
                case 17
                    handles.byte3 = uint8(ledOffset+37);
                case 18
                    handles.byte3 = uint8(ledOffset+43);
                case 19
                    handles.byte3 = uint8(ledOffset+22);
                case 20
                    handles.byte3 = uint8(ledOffset+29);
                case 21
                    handles.byte3 = uint8(ledOffset+14);
                case 22
                    handles.byte3 = uint8(ledOffset+36);
                case 23
                    handles.byte3 = uint8(ledOffset+50);
                case 24
                    handles.byte3 = uint8(ledOffset+21);
                case 25
                    handles.byte3 = uint8(ledOffset+13);
                case 26
                    handles.byte3 = uint8(ledOffset+28);
                case 27
                    handles.byte3 = uint8(ledOffset+42);
                case 28
                    handles.byte3 = uint8(ledOffset+20);
                case 29
                    handles.byte3 = uint8(ledOffset+35);
                case 30
                    handles.byte3 = uint8(ledOffset+12);
                case 31
                    handles.byte3 = uint8(ledOffset+27);
                case 32
                    handles.byte3 = uint8(ledOffset+19);
                case 33
                    handles.byte3 = uint8(ledOffset+49);
                case 34
                    handles.byte3 = uint8(ledOffset+11);
                case 35
                    handles.byte3 = uint8(ledOffset+34);
                case 36
                    handles.byte3 = uint8(ledOffset+41);
                case 37
                    handles.byte3 = uint8(ledOffset+26);
                case 38
                    handles.byte3 = uint8(ledOffset+18);
                case 39
                    handles.byte3 = uint8(ledOffset+10);
                case 40
                    handles.byte3 = uint8(ledOffset+33);
                case 41
                    handles.byte3 = uint8(ledOffset+25);
                case 42
                    handles.byte3 = uint8(ledOffset+17);
                case 43
                    handles.byte3 = uint8(ledOffset+48);
                case 44
                    handles.byte3 = uint8(ledOffset+9);
                case 45
                    handles.byte3 = uint8(ledOffset+40);
                case 46
                    handles.byte3 = uint8(ledOffset+32);
                case 47
                    handles.byte3 = uint8(ledOffset+24);
                case 48
                    handles.byte3 = uint8(ledOffset+16);
                case 49
                   handles.byte3 = uint8(ledOffset+8);
                case 50 %%%constant
                   handles.byte3 = uint8(ledOffset+56);
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.s,'%s',char(handles.byte1));
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.s,'%s',char(handles.byte2));
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.s,'%s',char(handles.byte3));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ledcontroll';
            app.figure1.Tag = 'figure1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'set led 1';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'set led 2';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'set led 3';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'off', '45.776hz', '51.498hz', '52.315hz', '58.855hz', '61.03hz', '68.664hz', '72.248hz', '73.242hz', '79.472hz-problem', '82.39hz', '82.569hz', '90.826hz', '91.552hz', '92.59hz', '95.36hz-problem', '96.330hz', '102.996hz', '105.82hz', '105.96hz', '108.99hz-problem', '115.596hz', '122.07hz', '123.456hz', '127.156hz-problem', '127.156hz', '137.32hz', '141.28hz', '144.496hz', '152.58hz', '158.945hz', '176.606hz', '183.105hz', '190.734hz', '192.661hz', '205.99hz', '211.92hz', '235.47hz', '254.31hz', '288.992hz', '317.89hz', '353.21hz', '366.211hz', '381.46hz', '411.98hz', '577.984hz', '635.78hz', '706.425hz', '762.94hz', 'constant'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'off';
            app.popupmenu2 = uidropdown(app.figure1);
            app.popupmenu2.Items = {'off', '45.776hz', '51.498hz', '52.315hz', '58.855hz', '61.03hz', '68.664hz', '72.248hz', '73.242hz', '79.472hz-problem', '82.39hz', '82.569hz', '90.826hz', '91.552hz', '92.59hz', '95.36hz-problem', '96.330hz', '102.996hz', '105.82hz', '105.96hz', '108.99hz-problem', '115.596hz', '122.07hz', '123.456hz', '127.156hz-problem', '127.156hz', '137.32hz', '141.28hz', '144.496hz', '152.58hz', '158.945hz', '176.606hz', '183.105hz', '190.734hz', '192.661hz', '205.99hz', '211.92hz', '235.47hz', '254.31hz', '288.992hz', '317.89hz', '353.21hz', '366.211hz', '381.46hz', '411.98hz', '577.984hz', '635.78hz', '706.425hz', '762.94hz', 'constant'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Value = 'off';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'off', '45.776hz', '51.498hz', '52.315hz', '58.855hz', '61.03hz', '68.664hz', '72.248hz', '73.242hz', '79.472hz-problem', '82.39hz', '82.569hz', '90.826hz', '91.552hz', '92.59hz', '95.36hz-problem', '96.330hz', '102.996hz', '105.82hz', '105.96hz', '108.99hz-problem', '115.596hz', '122.07hz', '123.456hz', '127.156hz-problem', '127.156hz', '137.32hz', '141.28hz', '144.496hz', '152.58hz', '158.945hz', '176.606hz', '183.105hz', '190.734hz', '192.661hz', '205.99hz', '211.92hz', '235.47hz', '254.31hz', '288.992hz', '317.89hz', '353.21hz', '366.211hz', '381.46hz', '411.98hz', '577.984hz', '635.78hz', '706.425hz', '762.94hz', 'constant'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Value = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ledcontroll_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ledcontroll_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end